classdef Rating_listening_1_GUI < matlab.apps.AppBase
    %RATING_LISTENING_1_GUI Runs magnitude estimation (rating) listening
    %test
    %   Runs magnitude estimation (rating) listening test for audio
    %   contained in \Audio subdirectory.
    %   
    %   Participant can play each audio file as many times as wanted.
    %   'Next' button is disabled until the audio has played through once.
    %   
    %   Participant responses are set to 'how loud do you perceive this
    %   sound to be?' and 'how annoying do you judge this sound to be?',
    %   with 0-10 continuous sliding rating scales.
    %   
    %   Records play/next function timing for further response analysis.
    %   
    %   Outputs to .xlsx spreadsheet files (1 response data, 1 timing
    %   data).
    %   
    %   Requirements
    %   ------------
    %   Audio Toolbox
    %   DSP Systems Toolbox
    %   
    %   Assumptions
    %   -----------
    %   The audio device used must support the audio file properties. For
    %   example, an error will arise if the output device does not support
    %   the number of channels in an audio file. The device to be used is
    %   selected from those detected on launch. On Windows OS, the driver
    %   type is selected prior to selecting a device compatible with the
    %   selected driver.
    %   
    %   Ownership and Quality Assurance
    %   -------------------------------
    %   Author: Mike JB Lotinga (m.j.lotinga@edu.salford.ac.uk)
    %   Institution: University of Salford
    %   
    %   Date created: 06/10/2023
    %   Date last modified: 09/10/2023
    %   MATLAB version: 2023b
    %   
    %   Copyright statement: This file and code is part of work undertaken
    %   within the RefMap project (www.refmap.eu), and is subject to
    %   licence as detailed in the code repository
    %   (https://github.com/acoustics-code-salford/refmap-psychoacoustics)
    %   
    %   This code was developed from original files 'Test_Part2.mlapp' and
    %   'Test_Part2.mlapp' authored by University of Salford
    %   students, and from 'AB_listening_test_2_GUI.mlapp developed by
    %   Michael Lotinga'.
    %   
    %   Checked by:
    %   Date last checked:

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        READMETab               matlab.ui.container.Tab
        GridLayout3             matlab.ui.container.GridLayout
        Instruction_3           matlab.ui.control.Label
        TESTTab                 matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        Instruction             matlab.ui.control.Label
        Instruction_2           matlab.ui.control.Label
        PlaySwitchLabel         matlab.ui.control.Label
        PlaySwitch              matlab.ui.control.Switch
        Lamp                    matlab.ui.control.Lamp
        StopButton              matlab.ui.control.Button
        LoudQLabel              matlab.ui.control.Label
        LoudnessSlider          matlab.ui.control.Slider
        NotatallloudLabel       matlab.ui.control.Label
        VeryloudLabel           matlab.ui.control.Label
        AnnoyQLabel             matlab.ui.control.Label
        AnnoyanceSlider         matlab.ui.control.Slider
        NotatallannoyingLabel   matlab.ui.control.Label
        ExtremelyannoyingLabel  matlab.ui.control.Label
        NextButton              matlab.ui.control.Button
        CurrentN                matlab.ui.control.Label
        ofLabel                 matlab.ui.control.Label
        TotalN                  matlab.ui.control.Label
        trialsLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        directory  % Main working directory
        subdirTest  % Subdirectory for test stimuli
        
        StimuliTest  % Test stimuli set

        readerTest  % dsp.AudioFileReader object for current test stimulus

        player  % audioDeviceWriter object
        audiodevice  % audio device to use
        audiodriver  % audio device driver to use

        SamplesPerFrame  % number of samples per frame to use for audio device
        SamplesPerFrameInputFig  % UI figure to input samples per frame
        SamplesPerFrameKnob  % UI control for input figure
        SamplesPerFrameButton  % UI control for input figure
        
        NTrials  % Total number of trials
        nTrial  % Trial number counter
        
        ParticipantNo  % Participant ID number
        Participant_TestData  % Participant test data output table

        t_date  % timestamp date
        t_time  % timestamp time

        Timescale  % Test timing data output table

        % PlaySwitched  % flag to indicate Play switched on
        PlayComplete  % flag to indicate stimulus played completely

        PlaySwitchVal  % value of the Play switch

        TogglePlay  % flag to toggle play of stimulus

    end
    
    methods (Access = private)
        % function to initialise audio device and driver selection
        function AudioDeviceDriverSelection(app)
            % select from connected audio devices (Windows OS)
            if ispc
                % check which driver devices are present
                drivers = ["ASIO", "WASAPI", "DirectSound"];
                driversOn = [];
                for ii = 1:length(drivers)
                    adw = audioDeviceWriter('Driver', drivers(ii));
                    devices = getAudioDevices(adw);
                    if contains('No audio output device detected', devices)
                    else
                        driversOn = cat(1, driversOn, [drivers(ii)]);
                    end  % end of if branch for storing drivers present
                end  % end of if branch for identifying drivers present
                
                % open selection dialog for device drivers detected
                tfDriver = 0;
                while tfDriver == 0    
                    [indx,tfDriver] = listdlg('ListString', driversOn,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio driver type");
                end  % end while loop for audio driver selection

                % assign driver type
                app.audiodriver = driversOn(indx);

                % assign devices for selected audio driver
                adw = audioDeviceWriter('Driver', app.audiodriver);
                devices = getAudioDevices(adw);

                % open selection dialog for audio devices
                tfDevice = 0;
                while tfDevice == 0
                    
                    [indx,tfDevice] = listdlg('ListString', devices,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio output device");
                end  % end while loop for audio device selection

                % assign device
                app.audiodevice = devices{indx};

            else  % mac OS and linux
                % assign devices for selected driver
                adw = audioDeviceWriter;
                devices = getAudioDevices(adw);

                % open selection dialog for devices
                tfDevice = 0;
                while tfDevice == 0
                    
                    [indx,tfDevice] = listdlg('ListString', devices,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio output device");
                end

                % assign device
                app.audiodevice = devices{indx};
                app.audiodriver = [];  % mac OS and linux use native drivers
            end  % end of if branch to check connected audio devices
            release(adw)

        end

        % interface function to select samples per frame value for audio device
        function SamplesPerFrameInput(app)
            app.SamplesPerFrameInputFig = uifigure('Name', "Samples/frame",...
                                                   'Position',...
                                                   [300, 250, 250, 250]);
            movegui(app.SamplesPerFrameInputFig, 'center');
            lbl = uilabel(app.SamplesPerFrameInputFig, 'Position', [10, 180, 220, 50],...
                          'VerticalAlignment', 'top', 'WordWrap', 'on');
            lbl.Text = "Set the number of samples per frame for the selected audio device";
            
            % input knob
            app.SamplesPerFrameKnob = uiknob(app.SamplesPerFrameInputFig,...
                                             "discrete");
            app.SamplesPerFrameKnob.Items = ["512", "1024", "2048", "4096", "8192"];
            app.SamplesPerFrameKnob.Value = app.SamplesPerFrameKnob.Items(2);
            % assign knob callback function
            app.SamplesPerFrameKnob.ValueChangedFcn = {@app.SamplesPerFrameInputChange};
            
            % input button
            app.SamplesPerFrameButton = uibutton(app.SamplesPerFrameInputFig,...
                                                 'push', 'Text', 'OK',...
                                                 'Position', [100, 10, 60, 40]);
            % assign button callback function
            app.SamplesPerFrameButton.ButtonPushedFcn = {@app.CloseSamplesPerFrameInput};
        end

        % callback function for SamplesPerFrameInputFig knob value
        function SamplesPerFrameInputChange(app, ~, ~)
            % assign SamplesPerFrame value to variable
            app.SamplesPerFrame = str2double(app.SamplesPerFrameKnob.Value);
        end

        % callback function for SamplesPerFrameInputFig OK button
        function CloseSamplesPerFrameInput(app, ~, ~)
            % assign SamplesPerFrame value to variable
            app.SamplesPerFrame = str2double(app.SamplesPerFrameKnob.Value);
            close(app.SamplesPerFrameInputFig)  % close figure
        end

        % function to trigger when stimulus playback ends
        function StimTogglePlay = StimEnd(app, StimPlayer, StimSwitch, StimLamp)
            StimTogglePlay = StimOff(app, StimPlayer, StimSwitch, StimLamp);
        end  % end of StimEnd function
        
        % function to trigger when stimulus switched off
        function StimTogglePlay = StimOff(~, StimPlayer, StimSwitch, StimLamp)
            StimSwitch.Value = 'Off';
            StimLamp.Color = [0.5, 0.5, 0.5];
            StimTogglePlay = false;
            release(StimPlayer)
        end  % end of StimOff function
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';  % hide UI
            addpath(genpath(pwd));  % add subfolders to path

            % if results subfolder does not exist, create it
            if ~exist("Results", 'dir')
                mkdir("Results")
                addpath("Results")
            end  % end of results subfolder if branch

            % assign reference and test file folders
            app.directory = pwd;  % current working dir
            app.subdirTest = fullfile(app.directory, "Audio");  % Test stimuli dir

            % assign stimuli files to variables
            app.StimuliTest = shuffle(audioDatastore(app.subdirTest)).Files(1:end);

            % calculate total number of trials
            % number of test files
            app.NTrials = length(dir(fullfile(app.subdirTest, '*.wav')));
            
            % set switch initial state
            % app.PlaySwitched = 0;
            % set play complete initial state
            app.PlayComplete = 0;

            % assign trial counter
            app.nTrial = 1;
            
            % add initial trial count text to UI
            app.CurrentN.Text = "1";
            app.TotalN.Text = sprintf("%s", string(app.NTrials));
            
            % disable next button on start
            app.NextButton.Enable = 'off';
            
            % initialise participant data
            app.Participant_TestData = table();
            
            % initialise audio stimuli
            app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(1)));

            % select from connected audio devices (Windows OS)
            AudioDeviceDriverSelection(app);

            % open UI figure to enter SamplesPerFrame for audio device
            SamplesPerFrameInput(app);
            waitfor(app.SamplesPerFrameInputFig)  % keeps app from opening until close of input
            
            % sort out app visual
            movegui(app.UIFigure, 'center');
%             drawnow;
            app.UIFigure.WindowState = 'fullscreen';

            % open participant number entry dialog
            app.ParticipantNo = inputdlg({'Enter participant number'});

            % if dialog input is non-numeric or is cancelled, reopen dialog
            if isempty(app.ParticipantNo) || isnan(str2double(cell2mat(app.ParticipantNo(1))))
              startupFcn(app)
              app.UIFigure.WindowState = 'fullscreen';
              return
            end  % end of if branch for input dialog

            app.UIFigure.Visible = 'on';  % show UI
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            % assign switch value to property
            app.PlaySwitchVal = app.PlaySwitch.Value;
            
            % if switched on
            if strcmp(app.PlaySwitchVal, 'On')
                
                % update timing data
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.Timescale = [app.Timescale;...
                                 table(app.nTrial, string(app.t_date),...
                                 string(app.t_time), "Play Switch",...
                                 'VariableNames', ["Trial Number",...
                                                   "Date", "Time",...
                                                   "Button Pressed"])];
                % switch on lamp
                app.Lamp.Color = 'g';
                                
                % get audio file info
                fileInfo = audioinfo(app.readerTest.Filename);

                % convert BitsPerSample number into BitDepth string
                try
                    switch fileInfo.BitsPerSample
                        case 8
                            BitDepth = "8-bit integer";
                        case 16
                            BitDepth = "16-bit integer";
                        case 24
                            BitDepth = "24-bit integer";
                        case 32
                            BitDepth = "32-bit float";
                    end
                catch
                    BitDepth = "16-bit integer";
                end

                % create player object
                app.player = audioDeviceWriter('Device', app.audiodevice,...
                                               'Driver', app.audiodriver,...
                                               'SampleRate',...
                                               app.readerTest.SampleRate,...
                                               'BitDepth', BitDepth);


                % check device can support file channels
                if info(app.player).MaximumOutputChannels < fileInfo.NumChannels
                    error("Error: audio device used does not support number of channels in file")
                end

                % Call setup to reduce the computational load of initialization in an audio stream loop.
                % openExample('dsp/ReadFromFileAndWriteToAudioDeviceExample')
                setup(app.player, zeros(app.SamplesPerFrame, fileInfo.NumChannels))

                % start playback functionality
                app.TogglePlay = true;
                
                % while loop to stream audio data frames to player object
                try  % avoids error on app exit deletion if stimulus still playing
                    while ~isDone(app.readerTest) && app.TogglePlay
                        drawnow()  % updates app values
                        % if filereader reaches end of file or stimulus
                        % switched off, toggle the switch and lamp, and
                        % release the file reader object
                        if strcmp(app.PlaySwitchVal, 'Off')
                            app.TogglePlay = StimEnd(app, app.player, app.PlaySwitch, app.Lamp);
                            release(app.readerTest)
                            break

                        else  % keep playing file
                            audioData = app.readerTest();
                            app.player(audioData);

                        end  % end of if branch for end of stimulus file
                    end  % end of while loop for streaming audio to player
                    app.TogglePlay = StimEnd(app, app.player, app.PlaySwitch, app.Lamp);
                    release(app.readerTest)
                    app.PlayComplete = 1;
                catch
                end  % end of try catch branch

                % if stimulus played, enable next button
                if app.PlayComplete > 0
                    app.NextButton.Enable = 'on';
                end  % end of if branch for enabling next button

            % if switched off, stop stimulus
            else
                app.TogglePlay = StimOff(app, app.player, app.PlaySwitch, app.Lamp);
                release(app.readerTest)
            end  % end of if branch for stimulus switched on
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % switch off stimulus, release player and file reader
            app.TogglePlay = StimOff(app, app.player, app.PlaySwitch, app.Lamp);
            release(app.readerTest)

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % switch off stimulus, release player and file reader
            app.TogglePlay = StimOff(app, app.player, app.PlaySwitch, app.Lamp);
            release(app.readerTest)

            % reset flags to indicate stimuli played
            % app.PlaySwitched = 0;
            app.PlayComplete = 0;

            ResponseNames = ["Trial Number", "Stim File",...
                             "Loudness", "Annoyance"];

            % get filenames assigned to stimulus
            filepath = app.readerTest.Filename;
            [~, filename, ext] = fileparts(filepath);
            filename = {strcat(filename, ext)};

            % append participant test to table
            app.Participant_TestData = [app.Participant_TestData;...
                                        table(app.nTrial, filename,...
                                              app.LoudnessSlider.Value,...
                                              app.AnnoyanceSlider.Value,...
                                              'VariableNames', ResponseNames)];
            
            % append participant timing data to table
            app.t_date = datetime('now','Format','yyyy-MM-dd');
            app.t_time = datetime('now','Format','HH:mm:ss.SSS');
            
            app.Timescale = [app.Timescale;...
                             table(app.nTrial, string(app.t_date), ...
                                   string(app.t_time), "Next Button", 'VariableNames',...
                                   ["Trial Number", "Date", "Time", ...
                                   "Button Pressed"])];
            
            % reset switch initial state
            app.PlaySwitch.Value = 'Off';
            
            % increment current trial number
            app.nTrial = app.nTrial + 1;

            % deactivate Next button for new trial
            app.NextButton.Enable = 'off';
            
            % if total trial number is reached, end test and write data
            if app.nTrial > app.NTrials             
                t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
                writetable(app.Participant_TestData, ...
                           fullfile(app.directory, "Results",...
                           sprintf('%s_RatingTest_ParticipantNo%s_Response.xlsx',...
                           t_end, string(app.ParticipantNo))));
                writetable(app.Timescale, ...
                           fullfile(app.directory, "Results",...
                           sprintf('%s_RatingTest_ParticipantNo%s_Timing.xlsx',...
                           t_end, string(app.ParticipantNo))));

                app.UIFigure.Visible = 'off';
                msgbox("Thank you for completing the test", ...
                       "Test complete", "help");
                delete(app);
            else  % if test files remaining, continue with next stimulus
                % update trial count on UI
                app.CurrentN.Text = string(app.nTrial);

                % re-initialise reader object
                app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrial)));

            end  % end of if branch for total number of trials reached

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.READMETab = uitab(app.TabGroup);
            app.READMETab.Title = 'README';
            app.GridLayout3 = uigridlayout(app.READMETab);
            app.Instruction_3 = uilabel(app.GridLayout3);
            app.Instruction_3.Text = {'In each trial of this test, a sound is presented. Your task is to rate each sound against the judgement scales. There are no correct or incorrect answers in this test, only personal judgements of subjective impressions.'; ''; 'Each sound is played back using the controls. To advance to the next trial, each sound must be played to completion once. When each sound has completed playing, the playback control will automatically switch off. Sounds can also be stopped prematurely using the controls. Each sound can be played back as many times as desired.'; ''; 'Please enter your rating for each sound using the slider controls. When you have entered your ratings and advanced to the next trial, you will not be able to return to a previous trial to change your ratings.'; ''; 'Please open the ''TEST'' tab above to start the test. You can review this information at any time during the test by clicking on the ''README'' tab above.'};
            app.TESTTab = uitab(app.TabGroup);
            app.TESTTab.Title = 'TEST';
            app.GridLayout2 = uigridlayout(app.TESTTab);
            app.trialsLabel = uilabel(app.GridLayout2);
            app.trialsLabel.Text = 'trials';
            app.TotalN = uilabel(app.GridLayout2);
            app.TotalN.Text = 'N';
            app.ofLabel = uilabel(app.GridLayout2);
            app.ofLabel.Text = 'of';
            app.CurrentN = uilabel(app.GridLayout2);
            app.CurrentN.Text = 'n';
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ExtremelyannoyingLabel = uilabel(app.GridLayout2);
            app.ExtremelyannoyingLabel.Text = 'Extremely annoying';
            app.NotatallannoyingLabel = uilabel(app.GridLayout2);
            app.NotatallannoyingLabel.Text = 'Not at all annoying';
            app.AnnoyanceSlider = uislider(app.GridLayout2);
            app.AnnoyanceSlider.Limits = [0 10];
            app.AnnoyanceSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AnnoyanceSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AnnoyQLabel = uilabel(app.GridLayout2);
            app.AnnoyQLabel.Text = 'How annoying do you judge this sound to be?';
            app.VeryloudLabel = uilabel(app.GridLayout2);
            app.VeryloudLabel.Text = 'Very loud';
            app.NotatallloudLabel = uilabel(app.GridLayout2);
            app.NotatallloudLabel.Text = 'Not at all loud';
            app.LoudnessSlider = uislider(app.GridLayout2);
            app.LoudnessSlider.Limits = [0 10];
            app.LoudnessSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.LoudnessSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.LoudQLabel = uilabel(app.GridLayout2);
            app.LoudQLabel.Text = 'How loud do you perceive this sound to be?';
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Lamp = uilamp(app.GridLayout2);
            app.PlaySwitch = uiswitch(app.GridLayout2, 'slider');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.PlaySwitchLabel = uilabel(app.GridLayout2);
            app.PlaySwitchLabel.Text = 'Play sound';
            app.Instruction_2 = uilabel(app.GridLayout2);
            app.Instruction_2.Text = '(You can listen to the sound more than once, but the sound must be played through once to advance.)';
            app.Instruction = uilabel(app.GridLayout2);
            app.Instruction.Text = 'Listen to the sound, and enter your judgement ratings. ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rating_listening_1_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end